classdef ipapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        FileMenu                   matlab.ui.container.Menu
        OpenMenu                   matlab.ui.container.Menu
        SaveMenu                   matlab.ui.container.Menu
        ExitMenu                   matlab.ui.container.Menu
        WaterMarkingMenu           matlab.ui.container.Menu
        BitplaneMenu               matlab.ui.container.Menu
        LSBMenu                    matlab.ui.container.Menu
        PointProcessingMenu        matlab.ui.container.Menu
        ComplementMenu             matlab.ui.container.Menu
        HistogramMenu              matlab.ui.container.Menu
        HistogramStretchingMenu    matlab.ui.container.Menu
        HistogramEqualizationMenu  matlab.ui.container.Menu
        FilterMenu                 matlab.ui.container.Menu
        AverageMenu                matlab.ui.container.Menu
        GaussianMenu               matlab.ui.container.Menu
        LaplacianMenu              matlab.ui.container.Menu
        LoGMenu                    matlab.ui.container.Menu
        UnsharpMenu                matlab.ui.container.Menu
        CustomizedMenu             matlab.ui.container.Menu
        CGMenu                     matlab.ui.container.Menu
        ROIMenu                    matlab.ui.container.Menu
        PointMenu                  matlab.ui.container.Menu
        LineMenu                   matlab.ui.container.Menu
        RectangleMenu              matlab.ui.container.Menu
        PolygonMenu                matlab.ui.container.Menu
        EllipseMenu                matlab.ui.container.Menu
        FreehandMenu               matlab.ui.container.Menu
        ROIaverageMenu             matlab.ui.container.Menu
        MosaicMenu                 matlab.ui.container.Menu
        ThresholdingMenu           matlab.ui.container.Menu
        OtsuMenu                   matlab.ui.container.Menu
        UserMenu                   matlab.ui.container.Menu
        EdgeMenu                   matlab.ui.container.Menu
        PrewittMenu                matlab.ui.container.Menu
        SobelMenu                  matlab.ui.container.Menu
        RobertsMenu                matlab.ui.container.Menu
        logMenu                    matlab.ui.container.Menu
        zerocrossMenu              matlab.ui.container.Menu
        CannyMenu                  matlab.ui.container.Menu
        approxcannyMenu            matlab.ui.container.Menu
        HoughMenu                  matlab.ui.container.Menu
        SnakeMenu                  matlab.ui.container.Menu
        FreeHandSnakeMenu          matlab.ui.container.Menu
        FourierMenu                matlab.ui.container.Menu
        LowpassMenu                matlab.ui.container.Menu
        HighpassMenu               matlab.ui.container.Menu
        ButterworthLowpassMenu     matlab.ui.container.Menu
        ButterworthHighpassMenu    matlab.ui.container.Menu
        GaussianLowpassMenu        matlab.ui.container.Menu
        GaussianHighpassMenu       matlab.ui.container.Menu
        DeepLearningMenu           matlab.ui.container.Menu
        GoogLeNetMenu              matlab.ui.container.Menu
        ThresholdingSlider         matlab.ui.control.Slider
        ThresholdingSliderLabel    matlab.ui.control.Label
        UIAxes2                    matlab.ui.control.UIAxes
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Data % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: OpenMenu
        function OpenMenuSelected(app, event)
            [app.Data.FileName, app.Data.PathName] = uigetfile( ...
              { '*.bmp;*.jpg;*.jpeg;*.pbm;*.pgm;*.png;*.ppm;*.tif;*.tiff',...
                'Image Files (*.bmp;*.jpg;*.jpeg;*.pbm;*.pgm;*.png;*.ppm;*.tif;*.tiff)';...
                '*.bmp','WindowsBitmap (*.bmp)';...
                '*.jpg;*.jpeg','JPEG(*.jpg;*.jpeg)';...
                '*.pbm','PortableBitmap (*.pbm)';...
                '*.pgm','PortableGraymap(*.pgm)';...
                '*.png','PortableNetwork Graphics (*.png)';...
                '*.ppm','PortablePixmap(*.ppm)';...
                '*.tif;*.tiff','TaggedImage File Format (*.tif;*.tiff)';...
                '*.*', 'All Files (*.*)'},...
                'Select an Image File to Open');
            figure(app.UIFigure);
            
            if(app.Data.FileName)==0
                return
            else
                %read in image
                app.Data.img= imread([app.Data.PathName, app.Data.FileName]);
                app.Data.result= app.Data.img;
                
                %show image
                imshow(app.Data.img, 'Parent', app.UIAxes);
                
                %get image information
                [app.Data.rows,  app.Data.cols,  app.Data.dim] = size(app.Data.img);
                info = imfinfo([app.Data.PathName,  app.Data.FileName]);
                app.Data.bitdepth= info.BitDepth;
                app.Data.ColorType= info.ColorType;
            end
        end

        % Menu selected function: SaveMenu
        function SaveMenuSelected(app, event)
            [filename, pathname, filterindex] = uiputfile( ...
                { '*.bmp','WindowsBitmap (*.bmp)'; ...
                  '*.jpg','JPEG(*.jpg)'; ...
                  '*.pbm','PortableBitmap (*.pbm)'; ...
                  '*.pgm','PortableGraymap(*.pgm)'; ...
                  '*.png','PortableNetwork Graphics (*.png)'; ...
                  '*.ppm','PortablePixmap(*.ppm)'; ...
                  '*.tif','TaggedImage File Format (*.tif)'}, ...
                  'Save Image as',...
                  'untitled');
            figure(app.UIFigure);

            if(filename)==0
                return
            else
                fmt(1,:)='bmp';
                fmt(2,:)='jpg';
                fmt(3,:)='pbm';
                fmt(4,:)='pgm';
                fmt(5,:)='png';
                fmt(6,:)='ppm';
                fmt(7,:)='tif';
                
                %write out image
                imwrite(app.Data.result,  [pathname, filename], fmt(filterindex,:));
            end
        end

        % Menu selected function: ExitMenu
        function ExitMenuSelected(app, event)
            delete(app);
        end

        % Menu selected function: BitplaneMenu
        function BitplaneMenuSelected(app, event)
            if(app.Data.dim)==1 && (app.Data.bitdepth)==8 %grayscale image
                
                %extract bitplane image
                imgbp= zeros(size(app.Data.img));
                for i=1:8
                    imgbp(:,:,i) = bitget(app.Data.img,i);
                end
                
                %show image
                SI = figure;
                figure(SI);
                for i=1:8
                    subplot(2,4,i);
                    imshow(imgbp(:,:,i), []);
                    title(['Bitplane', num2str(i)]);
                end
            else
                warndlg('The image is not an 8-bit grayscale image.','Warning')
                return
            end
        end

        % Menu selected function: LSBMenu
        function LSBMenuSelected(app, event)
            [FileName, PathName] = uigetfile( ...
                { '*.bmp;*.jpg;*.jpeg;*.pbm;*.pgm;*.png;*.ppm;*.tif;*.tiff',...
                  'Image Files (*.bmp;*.jpg;*.jpeg;*.pbm;*.pgm;*.png;*.ppm;*.tif;*.tiff)'; ...
                  '*.bmp','WindowsBitmap (*.bmp)';...
                  '*.jpg;*.jpeg','JPEG(*.jpg;*.jpeg)';...
                  '*.pbm','PortableBitmap (*.pbm)';...
                  '*.pgm','PortableGraymap(*.pgm)';...
                  '*.png','PortableNetwork Graphics (*.png)';...
                  '*.ppm','PortablePixmap(*.ppm)';...
                  '*.tif;*.tiff','TaggedImage File Format (*.tif;*.tiff)';...
                  '*.*', 'All Files (*.*)'},...
                  'Select a Watermark Image to Open');
            
            if(FileName)==0
                return
            else
                if(app.Data.dim)==1 && (app.Data.bitdepth)==8 %grayscale image
                    %read in watermark image
                    water_img= imread([PathName,FileName]);
                    water_info= imfinfo([PathName,FileName]);
                    [rows, cols, dim] = size(water_img)
                else
                    warndlg('The source image is not an 8-bit grayscale image.','Warning');
                    return;
                end
            end

            if(water_info.BitDepth)==1 %watermark is binary
                app.Data.result(1:rows,1:cols)=bitset(app.Data.img(1:rows,1:cols),1,water_img);
                %show image
                imshow(app.Data.result, 'Parent', app.UIAxes2);
                
                %compute and show PSNR
                mse= sum(sum((double(app.Data.img)-double(app.Data.result))^2))/(app.Data.rows*app.Data.cols);
                PSNR = 10*log10(255*255/mse)
            else
                %warndlg('The watermark image is not binary.','Warning');
                %return;
                image8bit = rgb2gray(water_img);
                cd2=double(image8bit);
                c0=mod(cd2,2);
                c1=mod(floor(cd2/2),2);
                c2=mod(floor(cd2/4),2);
                c3=mod(floor(cd2/8),2);
                c4=mod(floor(cd2/16),2);
                c5=mod(floor(cd2/32),2);
                c6=mod(floor(cd2/64),2);
                c7=mod(floor(cd2/128),2);
                app.Data.result(1:rows,1:cols)=bitset(app.Data.img(1:rows,1:cols),1,c7);
                %show image
                imshow(app.Data.result, 'Parent', app.UIAxes2);
                
                %compute and show PSNR
                mse= sum(sum((double(app.Data.img)-double(app.Data.result))^2))/(app.Data.rows*app.Data.cols);
                PSNR = 10*log10(255*255/mse)
            end
        end

        % Menu selected function: ComplementMenu
        function ComplementMenuSelected(app, event)
            %image complement
            app.Data.result= imcomplement(app.Data.img);
            
            %show image
            imshow(app.Data.result, 'Parent', app.UIAxes2);
        end

        % Menu selected function: HistogramMenu
        function HistogramMenuSelected(app, event)
            image_size=size(app.Data.img);
            dimension=numel(image_size);
            if(dimension==2)
                %image histogram
                SI = figure;
                figure(SI);
                imhist(app.Data.img);
            else
                SI = figure;
                figure(SI);
                imhist(app.Data.img(:,:,1));
                figure,imhist(app.Data.img(:,:,2));
                figure,imhist(app.Data.img(:,:,3));
            end
        end

        % Menu selected function: HistogramStretchingMenu
        function HistogramStretchingMenuSelected(app, event)
            %image stretching
            if(app.Data.dim)==1 && (app.Data.bitdepth)==8 %grayscale image
                app.Data.result= imadjust(app.Data.img);  
                
                %show image
                imshow(app.Data.result, 'Parent', app.UIAxes2);
            else
                %warndlg('The image should be an 8-bit grayscale image.','Warning')
                %return

                app.Data.result= rgb2ntsc(app.Data.img);
                imshow(app.Data.result, 'Parent', app.UIAxes2);
            end
        end

        % Menu selected function: HistogramEqualizationMenu
        function HistogramEqualizationMenuSelected(app, event)
            %image stretching
            if(app.Data.dim)==1 && (app.Data.bitdepth)==8 %grayscale image
                app.Data.result= histeq(app.Data.img);  
                
                %show image
                imshow(app.Data.result, 'Parent', app.UIAxes2);
            else
                %warndlg('The image should be an 8-bit grayscale image.','Warning')
                %return

                app.Data.result= rgb2ntsc(app.Data.img);
                imshow(app.Data.result, 'Parent', app.UIAxes2);
            end
        end

        % Menu selected function: AverageMenu
        function AverageMenuSelected(app, event)
            %Determine if input is structure array field
            if isfield(app.Data, 'img')
                %Create predefined 2-D filter
                h = fspecial('average', 7);
                %image 2-D digital filter
                app.Data.result= imfilter(app.Data.img, h);
                %show image
                imshow(app.Data.result, 'Parent', app.UIAxes2);
            else
                warndlg('The image file is not open.','Warning')
                return
            end
        end

        % Menu selected function: GaussianMenu
        function GaussianMenuSelected(app, event)
            if isfield(app.Data, 'img')
                %Create predefined 2-D filter
                h = fspecial('gaussian', 7);
                %image 2-D digital filter
                app.Data.result= imfilter(app.Data.img, h);
                %show image
                imshow(app.Data.result, 'Parent', app.UIAxes2);
            else
                warndlg('The image file is not open.','Warning')
                return
            end
        end

        % Menu selected function: LaplacianMenu
        function LaplacianMenuSelected(app, event)
            if isfield(app.Data, 'img')
                %Create predefined 2-D filter
                h = fspecial('laplacian');
                %image 2-D digital filter
                app.Data.result= imfilter(app.Data.img, h);
                %show image
                imshow(app.Data.result, 'Parent', app.UIAxes2);
            else
                warndlg('The image file is not open.','Warning')
                return
            end
        end

        % Menu selected function: LoGMenu
        function LoGMenuSelected(app, event)
            if isfield(app.Data, 'img')
                %Create predefined 2-D filter
                h = fspecial('log');
                %image 2-D digital filter
                app.Data.result= imfilter(app.Data.img, h);
                %show image
                imshow(app.Data.result, 'Parent', app.UIAxes2);
            else
                warndlg('The image file is not open.','Warning')
                return
            end
        end

        % Menu selected function: UnsharpMenu
        function UnsharpMenuSelected(app, event)
            if isfield(app.Data, 'img')
                %Create predefined 2-D filter
                h = fspecial('unsharp');
                %image 2-D digital filter
                app.Data.result= imfilter(app.Data.img, h);
                %show image
                imshow(app.Data.result, 'Parent', app.UIAxes2);
            else
                warndlg('The image file is not open.','Warning')
                return
            end
        end

        % Menu selected function: CustomizedMenu
        function CustomizedMenuSelected(app, event)
            %Create and open input dialog box
            prompt={'Rows (Mask Height):', 'Columns (Mask Width):'};
            name='Customized Filter';
            numlines=1;
            defaultanswer={'7', '7'};
            
            answer = inputdlg(prompt, name, numlines, defaultanswer);
            
            if isempty(answer) || isempty(answer{1}) || isempty(answer{2}) 
                warndlg('Error inputs.','Warning')
                return
            else
                %Create predefined 2-D filter
                h = fspecial('average', [str2double(answer{1}) str2double(answer{2})]);
                %image 2-D digital filter
                app.Data.result= imfilter(app.Data.img, h);
                %show image
                imshow(app.Data.result, 'Parent', app.UIAxes2);
            end
        end

        % Menu selected function: CGMenu
        function CGMenuSelected(app, event)
            %Create and open input dialog box
            prompt={'Rows (Mask Height):', 'Columns (Mask Width):', 'Sigma (double)'};
            name='Customized Filter';
            numlines=1;
            defaultanswer={'7', '7', '0.5'};
            
            answer = inputdlg(prompt, name, numlines, defaultanswer);
            
            if isempty(answer) || isempty(answer{1}) || isempty(answer{2}) 
                warndlg('Error inputs.','Warning')
                return
            else
                %Create predefined 2-D filter
                h = fspecial('gaussian', [str2double(answer{1}) str2double(answer{2})], str2double(answer{3}));
                %image 2-D digital filter
                app.Data.result= imfilter(app.Data.img, h);
                %show image
                imshow(app.Data.result, 'Parent', app.UIAxes2);
            end
        end

        % Menu selected function: PointMenu
        function PointMenuSelected(app, event)
            %set ROI function disable
            set(app.ROIMenu,'Enable','off');
            
            %draw point
            imshow(app.Data.img, 'Parent', app.UIAxes);
            roi= drawpoint(app.UIAxes, 'Color', 'r');
            roi.Position

            %set ROI function enable
            set(app.ROIMenu,'Enable','on');
        end

        % Menu selected function: LineMenu
        function LineMenuSelected(app, event)
            %set ROI function disable
            set(app.ROIMenu,'Enable','off');
            
            %draw line
            imshow(app.Data.img, 'Parent', app.UIAxes);
            roi= drawline(app.UIAxes, 'Color', 'r');
            roi.Position
            
            %set ROI function enable
            set(app.ROIMenu,'Enable','on');
        end

        % Menu selected function: RectangleMenu
        function RectangleMenuSelected(app, event)
            %set ROI function disable
            set(app.ROIMenu,'Enable','off');
            
            %draw rectangle
            imshow(app.Data.img, 'Parent', app.UIAxes);
            roi= drawrectangle(app.UIAxes, 'Color', 'r');
            roi.Position= round(roi.Position);
            roi.Position
            
            app.Data.result= app.Data.img(roi.Position(2)+1:roi.Position(2)+roi.Position(4), ...
                roi.Position(1)+1:roi.Position(1)+roi.Position(3),:);
            
            %show image
            imshow(app.Data.result, 'Parent', app.UIAxes2);
            
            %set ROI function enable
            set(app.ROIMenu,'Enable','on');
        end

        % Menu selected function: PolygonMenu
        function PolygonMenuSelected(app, event)
            %set ROI function disable
            set(app.ROIMenu,'Enable','off');
            
            %draw polygon
            imshow(app.Data.img, 'Parent', app.UIAxes);
            roi= drawpolygon(app.UIAxes, 'Color', 'r');
            roi.Position
            
            %create binary mask image from roi
            mask = createMask(roi);
            app.Data.result= uint8(mask.*double(app.Data.img));
            
            %show image
            imshow(app.Data.result, 'Parent', app.UIAxes2);

            %set ROI function enable
            set(app.ROIMenu,'Enable','on');
        end

        % Menu selected function: EllipseMenu
        function EllipseMenuSelected(app, event)
            %set ROI function disable
            set(app.ROIMenu,'Enable','off');
            
            %draw ellipse
            imshow(app.Data.img, 'Parent', app.UIAxes);
            roi= drawellipse(app.UIAxes, 'Color', 'r');
            roi.Position
            
            %create binary mask image from roi
            mask = createMask(roi);
            app.Data.result= uint8(mask.*double(app.Data.img));
            
            %show image
            imshow(app.Data.result, 'Parent', app.UIAxes2);
            %set ROI function enable
            set(app.ROIMenu,'Enable','on');
        end

        % Menu selected function: FreehandMenu
        function FreehandMenuSelected(app, event)
            %set ROI function disable
            set(app.ROIMenu,'Enable','off');
            
            %draw freehand
            imshow(app.Data.img, 'Parent', app.UIAxes);
            roi= drawfreehand(app.UIAxes, 'Color', 'r');
            
            %create binary mask image from roi
            mask = createMask(roi);
            app.Data.result= uint8(mask.*double(app.Data.img));
            
            %show image
            imshow(app.Data.result, 'Parent', app.UIAxes2);
            
            %set ROI function enable
            set(app.ROIMenu,'Enable','on');
        end

        % Menu selected function: ROIaverageMenu
        function ROIaverageMenuSelected(app, event)
            if(app.Data.dim)==1 && (app.Data.bitdepth)==8 %grayscale image
                %set ROI function disable
                set(app.ROIMenu,'Enable','off');
                %draw rectangle
                imshow(app.Data.img, 'Parent', app.UIAxes);
                roi= drawrectangle(app.UIAxes, 'Color', 'r');
                roi.Position
                %create binary mask image from roi
                mask = createMask(roi);
                %Create predefined 2-D filter
                f = fspecial('average',[25,25]); 
                %image ROI filter
                app.Data.result= roifilt2(f, app.Data.img, mask);
                %show image
                imshow(app.Data.result, 'Parent', app.UIAxes2);
                %set ROI function enable
                set(app.ROIMenu,'Enable','on');
            else
                warndlg('The image should be an 8-bit grayscale image.','Warning')
                return
            end
        end

        % Menu selected function: MosaicMenu
        function MosaicMenuSelected(app, event)
            %Create and open input dialog box
            prompt={'size:'};
            name='Mosaic Size';
            numlines=1;
            defaultanswer={'10'};
            
            answer = inputdlg(prompt, name, numlines, defaultanswer);
            pix_grp = str2double(answer{1});
            %set ROI function disable
            set(app.ROIMenu,'Enable','off');
            
            %draw rectangle
            imshow(app.Data.img, 'Parent', app.UIAxes);
            roi= drawrectangle(app.UIAxes, 'Color', 'r');
            roi.Position= round(roi.Position);
            roi.Position
            
            roimg= app.Data.img(roi.Position(2)+1:roi.Position(2)+roi.Position(4), ...
                roi.Position(1)+1:roi.Position(1)+roi.Position(3),:);
            roimg= imresize(roimg,[floor(roi.Position(4)/pix_grp) floor(roi.Position(3)/pix_grp)]);
            roimg= imresize(roimg,[roi.Position(4), roi.Position(3)],'nearest');
            %show image
            app.Data.result= app.Data.img;
            app.Data.result(roi.Position(2)+1:roi.Position(2)+roi.Position(4), roi.Position(1)+1:roi.Position(1)+roi.Position(3),:) = roimg;
            %show image
                imshow(app.Data.result, 'Parent', app.UIAxes2);
            %set ROI function enable
            set(app.ROIMenu,'Enable','on');
        end

        % Menu selected function: LowpassMenu
        function LowpassMenuSelected(app, event)
            if mod(size(app.Data.img, 1), 2)==0
                h = size(app.Data.img, 1)/2;
                hr = -h:h-1;
            else
                h = floor(size(app.Data.img, 1)/2);
                hr = -h:h;
            end
            if mod(size(app.Data.img, 2), 2)==0
                w = size(app.Data.img, 2)/2;
                wr= -w:w-1;
            else
                w = floor(size(app.Data.img, 2)/2);
                wr= -w:w;
            end
            %create a small circle mask
            [x,y] = meshgrid(wr,hr);
            z = sqrt(x.^2+y.^2);
            c = (z < 15);
            
            %Fourier lowpass filter
            app.Data.result= ifft2( ifftshift( fftshift( fft2(app.Data.img) ).*c ) );
            app.Data.result( find(app.Data.result< 0) ) = 0;
            app.Data.result( find(app.Data.result> 255) ) = 255;
            app.Data.result= uint8(app.Data.result);
            
            %show image
            imshow(app.Data.result, 'Parent', app.UIAxes2);
        end

        % Menu selected function: HighpassMenu
        function HighpassMenuSelected(app, event)
            if mod(size(app.Data.img, 1), 2)==0
                h = size(app.Data.img, 1)/2;
                hr = -h:h-1;
            else
                h = floor(size(app.Data.img, 1)/2);
                hr = -h:h;
            end
            if mod(size(app.Data.img, 2), 2)==0
                w = size(app.Data.img, 2)/2;
                wr= -w:w-1;
            else
                w = floor(size(app.Data.img, 2)/2);
                wr= -w:w;
            end
            %create a small circle mask
            [x,y] = meshgrid(wr,hr);
            z = sqrt(x.^2+y.^2);
            c = (z > 15);

            %Fourier highpass filter
            app.Data.result= ifft2( ifftshift( fftshift( fft2(app.Data.img) ).*c ) );
            app.Data.result( find(app.Data.result< 0) ) = 0;
            app.Data.result( find(app.Data.result> 255) ) = 255;
            app.Data.result= uint8(app.Data.result);
            
            %show image
            imshow(app.Data.result, 'Parent', app.UIAxes2);
        end

        % Menu selected function: ButterworthLowpassMenu
        function ButterworthLowpassMenuSelected(app, event)
            if mod(size(app.Data.img, 1), 2)==0
                h = size(app.Data.img, 1)/2;
                hr = -h:h-1;
            else
                h = floor(size(app.Data.img, 1)/2);
                hr = -h:h;
            end
            if mod(size(app.Data.img, 2), 2)==0
                w = size(app.Data.img, 2)/2;
                wr= -w:w-1;
            else
                w = floor(size(app.Data.img, 2)/2);
                wr= -w:w;
            end
    
            D = 15;
            n = 1;
            %create a Butterworth lowpass mask
            [x,y] = meshgrid(wr,hr);
            c = 1./(1+ ( (x.^2+y.^2) /D ).^(2*n) );

            %Fourier Butterworth lowpass filter
            app.Data.result= ifft2( ifftshift( fftshift( fft2(app.Data.img) ).*c ) );
            app.Data.result( find(app.Data.result< 0) ) = 0;
            app.Data.result( find(app.Data.result> 255) ) = 255;
            app.Data.result= uint8(app.Data.result);
            
            %show image
            imshow(app.Data.result, 'Parent', app.UIAxes2);
        end

        % Menu selected function: ButterworthHighpassMenu
        function ButterworthHighpassMenuSelected(app, event)
            if mod(size(app.Data.img, 1), 2)==0
                h = size(app.Data.img, 1)/2;
                hr = -h:h-1;
            else
                h = floor(size(app.Data.img, 1)/2);
                hr = -h:h;
            end
            if mod(size(app.Data.img, 2), 2)==0
                w = size(app.Data.img, 2)/2;
                wr= -w:w-1;
            else
                w = floor(size(app.Data.img, 2)/2);
                wr= -w:w;
            end
    
            D = 15;
            n = 1;
            %create a Butterworth lowpass mask
            [x,y] = meshgrid(wr,hr);
            c = 1-1./(1+ ( (x.^2+y.^2) /D ).^(2*n) );

            %Fourier Butterworth lowpass filter
            app.Data.result= ifft2( ifftshift( fftshift( fft2(app.Data.img) ).*c ) );
            app.Data.result( find(app.Data.result< 0) ) = 0;
            app.Data.result( find(app.Data.result> 255) ) = 255;
            app.Data.result= uint8(app.Data.result);
            
            %show image
            imshow(app.Data.result, 'Parent', app.UIAxes2);
        end

        % Menu selected function: GaussianLowpassMenu
        function GaussianLowpassMenuSelected(app, event)
            if mod(size(app.Data.img, 1), 2)==0
                h = size(app.Data.img, 1)/2;
                hr = -h:h-1;
            else
                h = floor(size(app.Data.img, 1)/2);
                hr = -h:h;
            end
            if mod(size(app.Data.img, 2), 2)==0
                w = size(app.Data.img, 2)/2;
                wr = -w:w-1;
            else
                w = floor(size(app.Data.img, 2)/2);
                wr = -w:w;
            end
    
            hsize = size(app.Data.img, 1:2);
            sigma = 10;
            %create a Gaussian lowpass mask
            c = mat2gray(fspecial('gaussian', hsize, sigma));

            %Fourier Butterworth lowpass filter
            app.Data.result= ifft2( ifftshift( fftshift( fft2(app.Data.img) ).*c ) );
            app.Data.result( find(app.Data.result< 0) ) = 0;
            app.Data.result( find(app.Data.result> 255) ) = 255;
            app.Data.result= uint8(app.Data.result);
            
            %show image
            imshow(app.Data.result, 'Parent', app.UIAxes2);
        end

        % Menu selected function: GaussianHighpassMenu
        function GaussianHighpassMenuSelected(app, event)
            if mod(size(app.Data.img, 1), 2)==0
                h = size(app.Data.img, 1)/2;
                hr = -h:h-1;
            else
                h = floor(size(app.Data.img, 1)/2);
                hr = -h:h;
            end
            if mod(size(app.Data.img, 2), 2)==0
                w = size(app.Data.img, 2)/2;
                wr= -w:w-1;
            else
                w = floor(size(app.Data.img, 2)/2);
                wr= -w:w;
            end
    
            hsize = size(app.Data.img, 1:2);
            sigma = 10;
            %create a Gaussian lowpass mask
            c = 1 - mat2gray(fspecial('gaussian', hsize, sigma));

            %Fourier Butterworth lowpass filter
            app.Data.result= ifft2( ifftshift( fftshift( fft2(app.Data.img) ).*c ) );
            app.Data.result( find(app.Data.result< 0) ) = 0;
            app.Data.result( find(app.Data.result> 255) ) = 255;
            app.Data.result= uint8(app.Data.result);
            
            %show image
            imshow(app.Data.result, 'Parent', app.UIAxes2);
        end

        % Menu selected function: OtsuMenu
        function OtsuMenuSelected(app, event)
            if(app.Data.dim)==1 && (app.Data.bitdepth)==8 %grayscale image
                %Global image threshold using Otsu's method
                level = graythresh(app.Data.img);
                app.Data.result= imbinarize(app.Data.img, level);
                level = round(level*255)
                [counts,x] = imhist(app.Data.img, 256);
                SI = figure;
                figure(SI);
                stem(x,counts,'.');
                hold on;
                xline(level,'--r');
                hold off;
            elseif(app.Data.dim)==3 %RGB image
                imgTemp = rgb2gray(app.Data.img);

                %level = (graythresh(app.Data.img(:,:,1)) | graythresh(app.Data.img(:,:,2)) | graythresh(app.Data.img(:,:,3)));
                
                %level_1 = graythresh(app.Data.img(:,:,1));
                %level_2 = graythresh(app.Data.img(:,:,2));
                %level_3 = graythresh(app.Data.img(:,:,3));
                %level = ((imgTemp > level_1) | (imgTemp == level_2) | (imgTemp < level_3));

                level = graythresh(imgTemp);
                app.Data.result = imbinarize(imgTemp, level);
                level = round(level*255);
                [counts,x] = imhist(imgTemp, 256);
                SI = figure;
                figure(SI);
                stem(x,counts,'.');
                hold on;
                xline(level,'--r');
                hold off;
            else
                warndlg('The image must be a grayscale image.','Warning')
                return
            end
            
            %show image
            imshow(app.Data.result, 'Parent', app.UIAxes2);
        end

        % Value changed function: ThresholdingSlider
        function ThresholdingSliderValueChanged(app, event)
            value = app.ThresholdingSlider.Value;
            
            if(app.Data.dim)==1 && (app.Data.bitdepth)==8 %grayscale image
                %Global image threshold using slider's value
                app.Data.result = imbinarize(app.Data.img, value/255);
                
                %show image
                imshow(app.Data.result, 'Parent', app.UIAxes2);
            elseif(app.Data.dim)==3 %RGB image
                %Global image threshold using slider's value
                app.Data.result = imbinarize(rgb2gray(app.Data.img), value/255)
                
                %show image
                imshow(app.Data.result, 'Parent', app.UIAxes2);
            else
                warndlg('The image must be a grayscale image.','Warning');
                return
            end
        end

        % Menu selected function: UserMenu
        function UserMenuSelected(app, event)
            if app.UserMenu.Checked==0
                set(app.UserMenu, 'Checked', 'on');
                set(app.ThresholdingSlider,  'visible',  'on');
                set(app.ThresholdingSliderLabel,  'visible',  'on');
            else
                set(app.UserMenu, 'Checked', 'off');
                set(app.ThresholdingSlider,  'visible',  'off');
                set(app.ThresholdingSliderLabel,  'visible',  'off');
            end
        end

        % Menu selected function: PrewittMenu
        function PrewittMenuSelected(app, event)
            if(app.Data.dim==1) %grayscale image
                %Find edges in the grayscale image
                app.Data.result = edge(app.Data.img, 'Prewitt');
            elseif(app.Data.dim)==3 %RGB image
                app.Data.result= edge(rgb2gray(app.Data.img), 'prewitt');
            else
                warndlg('The image cannot be processed.', 'Warning')
                return
            end
            
            %show image
            imshow(app.Data.result, 'Parent', app.UIAxes2);
        end

        % Menu selected function: SobelMenu
        function SobelMenuSelected(app, event)
            if(app.Data.dim==1) %grayscale image
                %Find edges in the grayscale image
                app.Data.result = edge(app.Data.img, 'sobel');
            elseif(app.Data.dim)==3 %RGB image
                app.Data.result= edge(rgb2gray(app.Data.img), 'sobel');
            else
                warndlg('The image cannot be processed.', 'Warning')
                return
            end
            
            %show image
            imshow(app.Data.result, 'Parent', app.UIAxes2);
        end

        % Menu selected function: RobertsMenu
        function RobertsMenuSelected(app, event)
            if(app.Data.dim==1) %grayscale image
                %Find edges in the grayscale image
                app.Data.result = edge(app.Data.img, 'roberts');
            elseif(app.Data.dim)==3 %RGB image
                app.Data.result= edge(rgb2gray(app.Data.img), 'roberts');
            else
                warndlg('The image cannot be processed.', 'Warning')
                return
            end
            
            %show image
            imshow(app.Data.result, 'Parent', app.UIAxes2);
        end

        % Menu selected function: logMenu
        function logMenuSelected(app, event)
            if(app.Data.dim==1) %grayscale image
                %Find edges in the grayscale image
                app.Data.result = edge(app.Data.img, 'log');
            elseif(app.Data.dim)==3 %RGB image
                app.Data.result= edge(rgb2gray(app.Data.img), 'log');
            else
                warndlg('The image cannot be processed.', 'Warning')
                return
            end
            
            %show image
            imshow(app.Data.result, 'Parent', app.UIAxes2);
        end

        % Menu selected function: zerocrossMenu
        function zerocrossMenuSelected(app, event)
            if(app.Data.dim==1) %grayscale image
                %Find edges in the grayscale image
                app.Data.result = edge(app.Data.img, 'zerocross');
            elseif(app.Data.dim)==3 %RGB image
                app.Data.result= edge(rgb2gray(app.Data.img), 'zerocross');
            else
                warndlg('The image cannot be processed.', 'Warning')
                return
            end
            
            %show image
            imshow(app.Data.result, 'Parent', app.UIAxes2);
        end

        % Menu selected function: CannyMenu
        function CannyMenuSelected(app, event)
            if(app.Data.dim==1) %grayscale image
                %Find edges in the grayscale image
                app.Data.result = edge(app.Data.img, 'canny');
            elseif(app.Data.dim)==3 %RGB image
                app.Data.result= edge(rgb2gray(app.Data.img), 'canny');
            else
                warndlg('The image cannot be processed.', 'Warning')
                return
            end
            
            %show image
            imshow(app.Data.result, 'Parent', app.UIAxes2);
        end

        % Menu selected function: approxcannyMenu
        function approxcannyMenuSelected(app, event)
            if(app.Data.dim==1) %grayscale image
                %Find edges in the grayscale image
                app.Data.result = edge(app.Data.img, 'approxcanny');
            elseif(app.Data.dim)==3 %RGB image
                app.Data.result= edge(rgb2gray(app.Data.img), 'approxcanny');
            else
                warndlg('The image cannot be processed.', 'Warning')
                return
            end
            
            %show image
            imshow(app.Data.result, 'Parent', app.UIAxes2);
        end

        % Menu selected function: HoughMenu
        function HoughMenuSelected(app, event)
            if(app.Data.dim==1) %grayscale image
                %Find edges in the grayscale image
                app.Data.result= edge(app.Data.img, 'Canny');
            elseif(app.Data.dim)==3 %RGB image
                app.Data.result= edge(rgb2gray(app.Data.img), 'canny');
            else
                warndlg('The image cannot be processed.','Warning')
                return
            end
            %houghtransform 
            [H,T,R] = hough(app.Data.result);
            P = houghpeaks(H,3); %設定前3個最大數目(最明顯的直線)
            lines = houghlines(app.Data.result,T,R,P);
            
            %show image with lines
            app.Data.result = uint8(255*app.Data.result);
            for k = 1:length(lines)
                app.Data.result = insertShape(app.Data.result,'Line',...
                    [lines(k).point1,lines(k).point2],'LineWidth',5,'Color','r');
            end
            imshow(app.Data.result, 'Parent', app.UIAxes2);
        end

        % Menu selected function: SnakeMenu
        function SnakeMenuSelected(app, event)
            if size(app.Data.img,3)==1 %grayscale image
                imshow(app.Data.img, 'Parent', app.UIAxes);
                SI = figure;
                figure(SI);
                imshow(app.Data.img,[]);
                %snake initialization
                cd snake
                [x,y] = snakeinit(1);
                [x,y] = snakeinterp(x,y,3,1); 
                snakedisp(x,y,'r');
                % Compute its edge map
                [gx,gy] = gradient(double(app.Data.img));
                magG= sqrt(gx.*gx+gy.*gy);
                %compute GVF of edge map
                [u,v] = GVF(magG, 0.2, 80);
                mag= sqrt(u.*u+v.*v);
                px = u./(mag+1e-10);
                py = v./(mag+1e-10);

                % snake deformation
                for i=1:20
                    [x,y] = snakedeform(x,y,0.05,0,1,0.6,px,py,5);
                    [x,y] = snakeinterp(x,y,3,1); 
                    if (rem(i,2) == 0)
                        snakedisp(x,y,'r');
                        pause(0.5);
                    end
                end
                cd ..
                
                %show image
                imshow(app.Data.img, 'Parent', app.UIAxes2);
                hold(app.UIAxes2, 'on');
                plot(app.UIAxes2,[ x;x(1)],[y;y(1)],'r');
                hold(app.UIAxes2, 'off');
            else
                warndlg('The image cannot be processed.','Warning')
                return
            end
        end

        % Menu selected function: FreeHandSnakeMenu
        function FreeHandSnakeMenuSelected(app, event)
            if size(app.Data.img,3)==1 %grayscale image
                set(app.FreeHandSnakeMenu,'Enable','off');
                
                %draw freehand
                imshow(app.Data.img, 'Parent', app.UIAxes);
                roi= drawfreehand(app.UIAxes, 'Color', 'r');
                
                %create binary mask image from roi
                mask = createMask(roi);
                app.Data.result= uint8(mask.*double(app.Data.img));
                
                %set ROI function enable
                set(app.FreeHandSnakeMenu,'Enable','on');

                %cd snake
                [y,x] = find(mask);
                % Compute its edge map
                [gx,gy] = gradient(double(app.Data.result));
                magG = sqrt(gx.*gx+gy.*gy);
                %compute GVF of edge map
                [u,v] = GVF(magG, 0.2, 80);
                mag= sqrt(u.*u+v.*v);
                px = u./(mag+1e-10);
                py = v./(mag+1e-10);

                % snake deformation
                for i=1:20
                    [x,y] = snakedeform(x,y,0.05,0,1,0.6,px,py,5);
                    [x,y] = snakeinterp(x,y,3,1); 
                    if (rem(i,2) == 0)
                        snakedisp(x,y,'r');
                        pause(0.5);
                    end
                end
                cd ..
                
                %show image
                imshow(app.Data.result, 'Parent', app.UIAxes2);
                %hold(app.UIAxes2, 'on');
                plot(app.UIAxes2,[ x;x(1)],[y;y(1)],'r');
                %hold(app.UIAxes2, 'off');
            else
                warndlg('The image cannot be processed.','Warning')
                return
            end
        end

        % Menu selected function: GoogLeNetMenu
        function GoogLeNetMenuSelected(app, event)
            if size(app.Data.img,3)==3 %color image
                %load the pretrained GoogLeNet network
                net = googlenet('Weights','none');
                
                % get the input size from layer 1
                inputSize = net.Layers(1).InputSize
                % getthe classification output names from the last layer
                classNames = net.Layers(end).ClassNames;
                % resize the image to the input size of the network
                I = imresize(app.Data.img,inputSize(1:2));
                [label, scores] = classify(net, I);
                
                %show image
                imshow(app.Data.img, 'Parent', app.UIAxes2);
                app.UIAxes2.Title.String = string(label)+", "+num2str(100*scores(classNames==label),3)+"%";
            elseif size(app.Data.img,3)==1 %grayscale image
                %load the pretrained GoogLeNet network
                net = googlenet('Weights','none');
                
                % get the input size from layer 1
                inputSize = net.Layers(1).InputSize
                % getthe classification output names from the last layer
                classNames = net.Layers(end).ClassNames;
                for i=1:3
                    img(:,:,i)=app.Data.img;
                end
                % resize the image to the input size of the network
                I = imresize(img,inputSize(1:2));
                [label, scores] = classify(net, I);
                
                %show image
                imshow(img, 'Parent', app.UIAxes2);
                app.UIAxes2.Title.String = string(label)+", "+num2str(100*scores(classNames==label),3)+"%";
            else
                warndlg('The image must be a color image.','Warning')
                return
            end 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected, true);
            app.OpenMenu.Text = 'Open';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveMenuSelected, true);
            app.SaveMenu.Text = 'Save';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @ExitMenuSelected, true);
            app.ExitMenu.Text = 'Exit';
            app.WaterMarkingMenu = uimenu(app.UIFigure);
            app.WaterMarkingMenu.Text = 'WaterMarking';
            app.BitplaneMenu = uimenu(app.WaterMarkingMenu);
            app.BitplaneMenu.MenuSelectedFcn = createCallbackFcn(app, @BitplaneMenuSelected, true);
            app.BitplaneMenu.Text = 'Bitplane';
            app.LSBMenu = uimenu(app.WaterMarkingMenu);
            app.LSBMenu.MenuSelectedFcn = createCallbackFcn(app, @LSBMenuSelected, true);
            app.LSBMenu.Text = 'LSB';
            app.PointProcessingMenu = uimenu(app.UIFigure);
            app.PointProcessingMenu.Text = 'Point Processing';
            app.ComplementMenu = uimenu(app.PointProcessingMenu);
            app.ComplementMenu.MenuSelectedFcn = createCallbackFcn(app, @ComplementMenuSelected, true);
            app.ComplementMenu.Text = 'Complement';
            app.HistogramMenu = uimenu(app.PointProcessingMenu);
            app.HistogramMenu.MenuSelectedFcn = createCallbackFcn(app, @HistogramMenuSelected, true);
            app.HistogramMenu.Text = 'Histogram';
            app.HistogramStretchingMenu = uimenu(app.PointProcessingMenu);
            app.HistogramStretchingMenu.MenuSelectedFcn = createCallbackFcn(app, @HistogramStretchingMenuSelected, true);
            app.HistogramStretchingMenu.Text = 'Histogram Stretching';
            app.HistogramEqualizationMenu = uimenu(app.PointProcessingMenu);
            app.HistogramEqualizationMenu.MenuSelectedFcn = createCallbackFcn(app, @HistogramEqualizationMenuSelected, true);
            app.HistogramEqualizationMenu.Text = 'Histogram Equalization';
            app.FilterMenu = uimenu(app.UIFigure);
            app.FilterMenu.Text = 'Filter';
            app.AverageMenu = uimenu(app.FilterMenu);
            app.AverageMenu.MenuSelectedFcn = createCallbackFcn(app, @AverageMenuSelected, true);
            app.AverageMenu.Text = 'Average';
            app.GaussianMenu = uimenu(app.FilterMenu);
            app.GaussianMenu.MenuSelectedFcn = createCallbackFcn(app, @GaussianMenuSelected, true);
            app.GaussianMenu.Text = 'Gaussian';
            app.LaplacianMenu = uimenu(app.FilterMenu);
            app.LaplacianMenu.MenuSelectedFcn = createCallbackFcn(app, @LaplacianMenuSelected, true);
            app.LaplacianMenu.Text = 'Laplacian';
            app.LoGMenu = uimenu(app.FilterMenu);
            app.LoGMenu.MenuSelectedFcn = createCallbackFcn(app, @LoGMenuSelected, true);
            app.LoGMenu.Text = 'LoG';
            app.UnsharpMenu = uimenu(app.FilterMenu);
            app.UnsharpMenu.MenuSelectedFcn = createCallbackFcn(app, @UnsharpMenuSelected, true);
            app.UnsharpMenu.Text = 'Unsharp';
            app.CustomizedMenu = uimenu(app.FilterMenu);
            app.CustomizedMenu.MenuSelectedFcn = createCallbackFcn(app, @CustomizedMenuSelected, true);
            app.CustomizedMenu.Text = 'Customized';
            app.CGMenu = uimenu(app.FilterMenu);
            app.CGMenu.MenuSelectedFcn = createCallbackFcn(app, @CGMenuSelected, true);
            app.CGMenu.Text = 'CG';
            app.ROIMenu = uimenu(app.UIFigure);
            app.ROIMenu.Text = 'ROI';
            app.PointMenu = uimenu(app.ROIMenu);
            app.PointMenu.MenuSelectedFcn = createCallbackFcn(app, @PointMenuSelected, true);
            app.PointMenu.Text = 'Point';
            app.LineMenu = uimenu(app.ROIMenu);
            app.LineMenu.MenuSelectedFcn = createCallbackFcn(app, @LineMenuSelected, true);
            app.LineMenu.Text = 'Line';
            app.RectangleMenu = uimenu(app.ROIMenu);
            app.RectangleMenu.MenuSelectedFcn = createCallbackFcn(app, @RectangleMenuSelected, true);
            app.RectangleMenu.Text = 'Rectangle';
            app.PolygonMenu = uimenu(app.ROIMenu);
            app.PolygonMenu.MenuSelectedFcn = createCallbackFcn(app, @PolygonMenuSelected, true);
            app.PolygonMenu.Text = 'Polygon';
            app.EllipseMenu = uimenu(app.ROIMenu);
            app.EllipseMenu.MenuSelectedFcn = createCallbackFcn(app, @EllipseMenuSelected, true);
            app.EllipseMenu.Text = 'Ellipse';
            app.FreehandMenu = uimenu(app.ROIMenu);
            app.FreehandMenu.MenuSelectedFcn = createCallbackFcn(app, @FreehandMenuSelected, true);
            app.FreehandMenu.Text = 'Freehand';
            app.ROIaverageMenu = uimenu(app.ROIMenu);
            app.ROIaverageMenu.MenuSelectedFcn = createCallbackFcn(app, @ROIaverageMenuSelected, true);
            app.ROIaverageMenu.Text = 'ROIaverage';
            app.MosaicMenu = uimenu(app.ROIMenu);
            app.MosaicMenu.MenuSelectedFcn = createCallbackFcn(app, @MosaicMenuSelected, true);
            app.MosaicMenu.Text = 'Mosaic';
            app.ThresholdingMenu = uimenu(app.UIFigure);
            app.ThresholdingMenu.Text = 'Thresholding';
            app.OtsuMenu = uimenu(app.ThresholdingMenu);
            app.OtsuMenu.MenuSelectedFcn = createCallbackFcn(app, @OtsuMenuSelected, true);
            app.OtsuMenu.Text = 'Otsu';
            app.UserMenu = uimenu(app.ThresholdingMenu);
            app.UserMenu.MenuSelectedFcn = createCallbackFcn(app, @UserMenuSelected, true);
            app.UserMenu.Text = 'User';
            app.EdgeMenu = uimenu(app.UIFigure);
            app.EdgeMenu.Text = 'Edge';
            app.PrewittMenu = uimenu(app.EdgeMenu);
            app.PrewittMenu.MenuSelectedFcn = createCallbackFcn(app, @PrewittMenuSelected, true);
            app.PrewittMenu.Text = 'Prewitt';
            app.SobelMenu = uimenu(app.EdgeMenu);
            app.SobelMenu.MenuSelectedFcn = createCallbackFcn(app, @SobelMenuSelected, true);
            app.SobelMenu.Text = 'Sobel';
            app.RobertsMenu = uimenu(app.EdgeMenu);
            app.RobertsMenu.MenuSelectedFcn = createCallbackFcn(app, @RobertsMenuSelected, true);
            app.RobertsMenu.Text = 'Roberts';
            app.logMenu = uimenu(app.EdgeMenu);
            app.logMenu.MenuSelectedFcn = createCallbackFcn(app, @logMenuSelected, true);
            app.logMenu.Text = 'log';
            app.zerocrossMenu = uimenu(app.EdgeMenu);
            app.zerocrossMenu.MenuSelectedFcn = createCallbackFcn(app, @zerocrossMenuSelected, true);
            app.zerocrossMenu.Text = 'zerocross';
            app.CannyMenu = uimenu(app.EdgeMenu);
            app.CannyMenu.MenuSelectedFcn = createCallbackFcn(app, @CannyMenuSelected, true);
            app.CannyMenu.Text = 'Canny';
            app.approxcannyMenu = uimenu(app.EdgeMenu);
            app.approxcannyMenu.MenuSelectedFcn = createCallbackFcn(app, @approxcannyMenuSelected, true);
            app.approxcannyMenu.Text = 'approxcanny';
            app.HoughMenu = uimenu(app.EdgeMenu);
            app.HoughMenu.MenuSelectedFcn = createCallbackFcn(app, @HoughMenuSelected, true);
            app.HoughMenu.Text = 'Hough';
            app.SnakeMenu = uimenu(app.EdgeMenu);
            app.SnakeMenu.MenuSelectedFcn = createCallbackFcn(app, @SnakeMenuSelected, true);
            app.SnakeMenu.Text = 'Snake';
            app.FreeHandSnakeMenu = uimenu(app.EdgeMenu);
            app.FreeHandSnakeMenu.MenuSelectedFcn = createCallbackFcn(app, @FreeHandSnakeMenuSelected, true);
            app.FreeHandSnakeMenu.Text = 'FreeHandSnake';
            app.FourierMenu = uimenu(app.UIFigure);
            app.FourierMenu.Text = 'Fourier';
            app.LowpassMenu = uimenu(app.FourierMenu);
            app.LowpassMenu.MenuSelectedFcn = createCallbackFcn(app, @LowpassMenuSelected, true);
            app.LowpassMenu.Text = 'Lowpass';
            app.HighpassMenu = uimenu(app.FourierMenu);
            app.HighpassMenu.MenuSelectedFcn = createCallbackFcn(app, @HighpassMenuSelected, true);
            app.HighpassMenu.Text = 'Highpass';
            app.ButterworthLowpassMenu = uimenu(app.FourierMenu);
            app.ButterworthLowpassMenu.MenuSelectedFcn = createCallbackFcn(app, @ButterworthLowpassMenuSelected, true);
            app.ButterworthLowpassMenu.Text = 'ButterworthLowpass';
            app.ButterworthHighpassMenu = uimenu(app.FourierMenu);
            app.ButterworthHighpassMenu.MenuSelectedFcn = createCallbackFcn(app, @ButterworthHighpassMenuSelected, true);
            app.ButterworthHighpassMenu.Text = 'ButterworthHighpass';
            app.GaussianLowpassMenu = uimenu(app.FourierMenu);
            app.GaussianLowpassMenu.MenuSelectedFcn = createCallbackFcn(app, @GaussianLowpassMenuSelected, true);
            app.GaussianLowpassMenu.Text = 'GaussianLowpass';
            app.GaussianHighpassMenu = uimenu(app.FourierMenu);
            app.GaussianHighpassMenu.MenuSelectedFcn = createCallbackFcn(app, @GaussianHighpassMenuSelected, true);
            app.GaussianHighpassMenu.Text = 'GaussianHighpass';
            app.DeepLearningMenu = uimenu(app.UIFigure);
            app.DeepLearningMenu.Text = 'Deep Learning';
            app.GoogLeNetMenu = uimenu(app.DeepLearningMenu);
            app.GoogLeNetMenu.MenuSelectedFcn = createCallbackFcn(app, @GoogLeNetMenuSelected, true);
            app.GoogLeNetMenu.Text = 'GoogLeNet';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Original Image')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Result Image')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.ThresholdingSliderLabel = uilabel(app.UIFigure);
            app.ThresholdingSliderLabel.Visible = 'off';
            app.ThresholdingSliderLabel.Text = 'Thresholding';
            app.ThresholdingSlider = uislider(app.UIFigure);
            app.ThresholdingSlider.Limits = [0 255];
            app.ThresholdingSlider.ValueChangedFcn = createCallbackFcn(app, @ThresholdingSliderValueChanged, true);
            app.ThresholdingSlider.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ipapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end