classdef ipapp2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        FileMenu        matlab.ui.container.Menu
        OpenMenu        matlab.ui.container.Menu
        SaveMenu        matlab.ui.container.Menu
        ExitMenu        matlab.ui.container.Menu
        ConversionMenu  matlab.ui.container.Menu
        rgb2grayMenu    matlab.ui.container.Menu
        resizeMenu      matlab.ui.container.Menu
        split2rgbMenu   matlab.ui.container.Menu
        rotateMenu      matlab.ui.container.Menu
        UIAxes2         matlab.ui.control.UIAxes
        UIAxes          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Data % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: OpenMenu
        function OpenMenuSelected(app, event)
            [app.Data.FileName, app.Data.PathName] = uigetfile( ...
              { '*.bmp;*.jpg;*.jpeg;*.pbm;*.pgm;*.png;*.ppm;*.tif;*.tiff',...
                'Image Files (*.bmp;*.jpg;*.jpeg;*.pbm;*.pgm;*.png;*.ppm;*.tif;*.tiff)';...
                '*.bmp','WindowsBitmap (*.bmp)';...
                '*.jpg;*.jpeg','JPEG(*.jpg;*.jpeg)';...
                '*.pbm','PortableBitmap (*.pbm)';...
                '*.pgm','PortableGraymap(*.pgm)';...
                '*.png','PortableNetwork Graphics (*.png)';...
                '*.ppm','PortablePixmap(*.ppm)';...
                '*.tif;*.tiff','TaggedImage File Format (*.tif;*.tiff)';...
                '*.*', 'All Files (*.*)'},...
                'Select an Image File to Open');
            figure(app.UIFigure);
            
            if(app.Data.FileName)==0
                return
            else
                %read in image
                app.Data.img= imread([app.Data.PathName, app.Data.FileName]);
                app.Data.result= app.Data.img;
                
                %show image
                imshow(app.Data.img, 'Parent', app.UIAxes);
                
                %get image information
                [app.Data.rows,  app.Data.cols,  app.Data.dim] = size(app.Data.img);
                info = imfinfo([app.Data.PathName,  app.Data.FileName]);
                app.Data.bitdepth= info.BitDepth;
                app.Data.ColorType= info.ColorType;
            end
        end

        % Menu selected function: SaveMenu
        function SaveMenuSelected(app, event)
            [filename, pathname, filterindex] = uiputfile( ...
                { '*.bmp','WindowsBitmap (*.bmp)'; ...
                  '*.jpg','JPEG(*.jpg)'; ...
                  '*.pbm','PortableBitmap (*.pbm)'; ...
                  '*.pgm','PortableGraymap(*.pgm)'; ...
                  '*.png','PortableNetwork Graphics (*.png)'; ...
                  '*.ppm','PortablePixmap(*.ppm)'; ...
                  '*.tif','TaggedImage File Format (*.tif)'}, ...
                  'Save Image as',...
                  'untitled');
            figure(app.UIFigure);

            if(filename)==0
                return
            else
                fmt(1,:)='bmp';
                fmt(2,:)='jpg';
                fmt(3,:)='pbm';
                fmt(4,:)='pgm';
                fmt(5,:)='png';
                fmt(6,:)='ppm';
                fmt(7,:)='tif';
                
                %write out image
                imwrite(app.Data.result,  [pathname, filename], fmt(filterindex,:));
            end
        end

        % Menu selected function: ExitMenu
        function ExitMenuSelected(app, event)
            delete(app);
        end

        % Menu selected function: rgb2grayMenu
        function rgb2grayMenuSelected(app, event)
            if(app.Data.dim)==3
                %convert RGB to gray image
                app.Data.result= rgb2gray(app.Data.img);
                
                %show image
                imshow(app.Data.result, 'Parent', app.UIAxes2);
                
            else
                warndlg('This image is not a color image.','Warning')
                return
            end
        end

        % Menu selected function: resizeMenu
        function resizeMenuSelected(app, event)
            %Create and open input dialog box
            prompt={'Image Height:',...
                    'Image Width:',...
                    'Interpolation Method (1=nearest, 2=bilinear, 3=bicubic):'};
            name='Image Resizing';
            numlines=1;
            defaultanswer={num2str(app.Data.rows), num2str(app.Data.cols),   '1'};

            answer = inputdlg(prompt, name, numlines,  defaultanswer);

            if isempty(answer)
                return
            else
                switch answer{3}
                    case {'1'}
                        meth_str= 'nearest';
                    case {'2'}
                        meth_str= 'bilinear';
                    case {'3'}
                        meth_str= 'bicubic';
                end
                %Resize image
                app.Data.result= imresize(app.Data.img, [str2num(answer{1}) str2num(answer{2})], meth_str);
                
                %show image
                imshow(app.Data.result, 'Parent', app.UIAxes2);
            end
        end

        % Menu selected function: split2rgbMenu
        function split2rgbMenuSelected(app, event)
            if(app.Data.dim)==3
                %convert RGB to gray image
                app.Data.result = rgb2gray(app.Data.img);
                
                %show image
                for i=1:3
                    SI = figure;
                    figure(SI);
                    iptsetpref('ImshowBorder','tight');
                    
                    %removes figure border
                    imshow(app.Data.img(:,:,i));
                end                
            else
                warndlg('This image is not a color image.','Warning')
                return
            end
        end

        % Menu selected function: rotateMenu
        function rotateMenuSelected(app, event)
            app.Data.result = imrotate(app.Data.img, 90);
                
            %show image
            imshow(app.Data.result, 'Parent', app.UIAxes2);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected, true);
            app.OpenMenu.Text = 'Open';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveMenuSelected, true);
            app.SaveMenu.Text = 'Save';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @ExitMenuSelected, true);
            app.ExitMenu.Text = 'Exit';
            app.ConversionMenu = uimenu(app.UIFigure);
            app.ConversionMenu.Text = 'Conversion';
            app.rgb2grayMenu = uimenu(app.ConversionMenu);
            app.rgb2grayMenu.MenuSelectedFcn = createCallbackFcn(app, @rgb2grayMenuSelected, true);
            app.rgb2grayMenu.Text = 'rgb2gray';
            app.resizeMenu = uimenu(app.ConversionMenu);
            app.resizeMenu.MenuSelectedFcn = createCallbackFcn(app, @resizeMenuSelected, true);
            app.resizeMenu.Text = 'resize';
            app.split2rgbMenu = uimenu(app.ConversionMenu);
            app.split2rgbMenu.MenuSelectedFcn = createCallbackFcn(app, @split2rgbMenuSelected, true);
            app.split2rgbMenu.Text = 'split2rgb';
            app.rotateMenu = uimenu(app.ConversionMenu);
            app.rotateMenu.MenuSelectedFcn = createCallbackFcn(app, @rotateMenuSelected, true);
            app.rotateMenu.Text = 'rotate';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Original Image')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Result Image')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ipapp2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end